classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        AudioSignalwithTwoSidedEchoesPanel  matlab.ui.container.Panel
        ButtonGroup_2            matlab.ui.container.ButtonGroup
        FrequencyDomainButton_2  matlab.ui.control.RadioButton
        TimeDomainButton_2       matlab.ui.control.RadioButton
        UIAxes_2                 matlab.ui.control.UIAxes
        AudioSignalPanel         matlab.ui.container.Panel
        ButtonGroup              matlab.ui.container.ButtonGroup
        FrequencyDomainButton    matlab.ui.control.RadioButton
        TimeDomainButton         matlab.ui.control.RadioButton
        UIAxes                   matlab.ui.control.UIAxes
        SaveEchoedVoicePanel     matlab.ui.container.Panel
        SaveButton               matlab.ui.control.Button
        PlayAudioFilePanel       matlab.ui.container.Panel
        EchoAudioButton          matlab.ui.control.Button
        InputAudioButton         matlab.ui.control.Button
        EchoParametersPanel      matlab.ui.container.Panel
        EchoButton               matlab.ui.control.Button
        OEditField               matlab.ui.control.NumericEditField
        OEditFieldLabel          matlab.ui.control.Label
        MEditField               matlab.ui.control.NumericEditField
        MEditFieldLabel          matlab.ui.control.Label
        NEditField               matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        OpenAudioFilePanel       matlab.ui.container.Panel
        OpenAudioFileButton      matlab.ui.control.Button
    end

    
    properties (Access = public)
        y % Description
        Fs
        FileName
        PathName
        info
        F1
        yecho
        b
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: ButtonGroup, OpenAudioFileButton
        function OpenAudioFileButtonPushed(app, event)
            [app.FileName,app.PathName]=uigetfile({'*.wav; *.aif'}, 'Select audio file');
            [app.y, app.Fs] = audioread([app.PathName,app.FileName]);
            app.info = audioinfo(app.FileName);
            app.y=app.y(:,1);
            app.y=app.y';
            switch app.ButtonGroup.SelectedObject.Text
                case "Time Domain"
                    app.UIAxes.XLabel.String="Time(s)";
                    app.UIAxes.YLabel.String="Amplitude";
                    t = 0:seconds(1/app.Fs):seconds(app.info.Duration);
                    t=t(1:end-1);
                    plot(app.UIAxes,t,app.y);
                case "Frequency Domain"
                    app.UIAxes.XLabel.String="Frequency(Hz)";
                    app.UIAxes.YLabel.String="Magnitude";
                    fftsignal=fft(app.y);
                    fftsignal = fftshift(fftsignal);
                    f=app.Fs/2*linspace(-1,1,app.Fs);
                    plot(app.UIAxes,w/pi,abs(fftsignal));
           end
        end

        % Button pushed function: InputAudioButton
        function InputAudioButtonPushed(app, event)
            sound(app.y,app.Fs)
        end

        % Button pushed function: EchoButton
        function EchoButtonPushed(app, event)
            M=app.MEditField.Value;
            N=app.NEditField.Value;
            O=app.OEditField.Value;
            app.yecho=zeros(1,N*M+length(app.y));
            app.b=length(app.yecho);
           for i=0:N
                a=length([zeros(1,i*M),app.y]);
                app.yecho=app.yecho+[zeros(1,i*M) O^i*app.y zeros(1,app.b-a)];
           end
           switch app.ButtonGroup_2.SelectedObject.Text
                case "Time Domain"
                    app.UIAxes_2.XLabel.String="Time(s)";
                    app.UIAxes_2.YLabel.String="Amplitude";
                    N=length(app.yecho);
                    t = linspace(0,N/app.Fs,N);
                    plot(app.UIAxes_2,t,app.yecho);
                case "Frequency Domain"
                    app.UIAxes_2.XLabel.String="Frequency(Hz)";
                    app.UIAxes_2.YLabel.String="Magnitude";
                    t=linspace(0,length(app.b)/app.Fs,length(app.b));
                    fftsignal=fft(app.yecho);
                    fftsignal = fftshift(fftsignal);
                    f=app.Fs/2*linspace(-1,1,app.Fs);
                    plot(app.UIAxes_2,abs(fftsignal));
           end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            switch app.ButtonGroup.SelectedObject.Text
                case "Time Domain"
                    app.UIAxes.XLabel.String="Time(s)";
                    app.UIAxes.YLabel.String="Amplitude";
                    t = 0:seconds(1/app.Fs):seconds(app.info.Duration);
                    t=t(1:end-1);
                    plot(app.UIAxes,t,app.y);
                case "Frequency Domain"
                    app.UIAxes.XLabel.String="Frequency(Hz)";
                    app.UIAxes.YLabel.String="Magnitude";
                    fftsignal=fft(app.y);
                    fftsignal = fftshift(fftsignal);
                    f=app.Fs/2*linspace(-1,1,app.Fs);
                    plot(app.UIAxes,abs(fftsignal));
           end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            switch app.ButtonGroup_2.SelectedObject.Text
                case "Time Domain"
                    app.UIAxes_2.XLabel.String="Time(s)";
                    app.UIAxes_2.YLabel.String="Amplitude";
                    N=length(app.yecho);
                    t = linspace(0,N/app.Fs,N);
                    plot(app.UIAxes_2,t,app.yecho);
                case "Frequency Domain"
                    app.UIAxes_2.XLabel.String="Frequency(Hz)";
                    app.UIAxes_2.YLabel.String="Magnitude";
                    t=linspace(0,length(app.b)/app.Fs,length(app.b));
                    fftsignal=fft(app.yecho);
                    fftsignal = fftshift(fftsignal);
                    f=app.Fs/2*linspace(-1,1,app.Fs);
                    plot(app.UIAxes_2,abs(fftsignal));
           end
        end

        % Button pushed function: EchoAudioButton
        function EchoAudioButtonPushed(app, event)
            sound(app.yecho,app.Fs)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            audiowrite("echoed.wav",app.yecho,app.Fs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenAudioFilePanel = uipanel(app.UIFigure);
            app.OpenAudioFilePanel.Title = 'Open Audio File';
            app.OpenAudioFileButton = uibutton(app.OpenAudioFilePanel, 'push');
            app.OpenAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.OpenAudioFileButton.Text = 'Open Audio File';
            app.EchoParametersPanel = uipanel(app.UIFigure);
            app.EchoParametersPanel.Title = 'Echo Parameters';
            app.NEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.MEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.OEditFieldLabel = uilabel(app.EchoParametersPanel);
            app.OEditFieldLabel.Text = 'O';
            app.OEditField = uieditfield(app.EchoParametersPanel, 'numeric');
            app.EchoButton = uibutton(app.EchoParametersPanel, 'push');
            app.EchoButton.ButtonPushedFcn = createCallbackFcn(app, @EchoButtonPushed, true);
            app.EchoButton.Text = 'Echo';
            app.PlayAudioFilePanel = uipanel(app.UIFigure);
            app.PlayAudioFilePanel.Title = 'Play Audio File';
            app.InputAudioButton = uibutton(app.PlayAudioFilePanel, 'push');
            app.InputAudioButton.ButtonPushedFcn = createCallbackFcn(app, @InputAudioButtonPushed, true);
            app.InputAudioButton.Text = 'Input Audio ';
            app.EchoAudioButton = uibutton(app.PlayAudioFilePanel, 'push');
            app.EchoAudioButton.ButtonPushedFcn = createCallbackFcn(app, @EchoAudioButtonPushed, true);
            app.EchoAudioButton.Text = 'Echo Audio ';
            app.SaveEchoedVoicePanel = uipanel(app.UIFigure);
            app.SaveEchoedVoicePanel.Title = 'Save Echoed Voice';
            app.SaveButton = uibutton(app.SaveEchoedVoicePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.AudioSignalPanel = uipanel(app.UIFigure);
            app.AudioSignalPanel.Title = 'Audio Signal';
            app.UIAxes = uiaxes(app.AudioSignalPanel);
            title(app.UIAxes, 'Audio Signal')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Magnitude')
            zlabel(app.UIAxes, 'Z')
            app.ButtonGroup = uibuttongroup(app.AudioSignalPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.SizeChangedFcn = createCallbackFcn(app, @OpenAudioFileButtonPushed, true);
            app.TimeDomainButton = uiradiobutton(app.ButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FrequencyDomainButton = uiradiobutton(app.ButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            app.AudioSignalwithTwoSidedEchoesPanel = uipanel(app.UIFigure);
            app.AudioSignalwithTwoSidedEchoesPanel.Title = 'Audio Signal with Two-Sided Echoes';
            app.UIAxes_2 = uiaxes(app.AudioSignalwithTwoSidedEchoesPanel);
            title(app.UIAxes_2, 'Audio Signal with Two-Sided Echoes')
            xlabel(app.UIAxes_2, 'Time (s)')
            ylabel(app.UIAxes_2, 'Amplitude')
            zlabel(app.UIAxes_2, 'Z')
            app.ButtonGroup_2 = uibuttongroup(app.AudioSignalwithTwoSidedEchoesPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.TimeDomainButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.TimeDomainButton_2.Text = 'Time Domain';
            app.TimeDomainButton_2.Value = true;
            app.FrequencyDomainButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.FrequencyDomainButton_2.Text = 'Frequency Domain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end